classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CutoffFrequencyEditField     matlab.ui.control.NumericEditField
        RecordAudioButton            matlab.ui.control.Button
        PlayFilteredAudioButton      matlab.ui.control.Button
        LowPassFilterButton          matlab.ui.control.Button
        HighPassFilterButton         matlab.ui.control.Button
        BandPassFilterButton         matlab.ui.control.Button
        BandStopFilterButton         matlab.ui.control.Button
        AudioFilterApplicationLabel  matlab.ui.control.Label
        CurrentFilterLabel           matlab.ui.control.Label
        NoneLabel                    matlab.ui.control.Label
        UpperLimitEditField          matlab.ui.control.NumericEditField
        CutoffFrequencyLabel         matlab.ui.control.Label
        UpperLimitLabel              matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordAudioButton
        function RecordAudioButtonPushed(app, event)
            audioObject = audiorecorder;
            duration = 5;
            msgbox('Recording...');
            recordblocking(audioObject, duration);
            msgbox('Recording ended.');
            assignin('base', 'audioObject', audioObject);
            plot(app.UIAxes2, getaudiodata(audioObject)); 
        end

        % Callback function: CutoffFrequencyEditField, 
        % LowPassFilterButton
        function LowPassFilterButtonPushed(app, event)
            app.NoneLabel.Text = "Low-Pass";
            app.CutoffFrequencyLabel.Text = 'Cutoff Frequency';
            app.UpperLimitLabel.Visible = 'off';
            app.UpperLimitEditField.Visible = 'off';            
        end

        % Button pushed function: HighPassFilterButton
        function HighPassFilterButtonPushed(app, event)
            app.NoneLabel.Text = "High-Pass";
            app.CutoffFrequencyLabel.Text = 'Cutoff Frequency';
            app.UpperLimitLabel.Visible = 'off';
            app.UpperLimitEditField.Visible = 'off';
        end

        % Button pushed function: BandPassFilterButton
        function BandPassFilterButtonPushed(app, event)
            app.NoneLabel.Text = "Band-Pass";
            app.CutoffFrequencyLabel.Text = 'Lower Limit';
            app.UpperLimitLabel.Visible = 'on';
            app.UpperLimitEditField.Visible = 'on';
        end

        % Button pushed function: BandStopFilterButton
        function BandStopFilterButtonPushed(app, event)
            app.NoneLabel.Text = "Band-Stop";
            app.CutoffFrequencyLabel.Text = 'Lower Limit';
            app.UpperLimitLabel.Visible = 'on';
            app.UpperLimitEditField.Visible = 'on';
        end

        % Button pushed function: PlayFilteredAudioButton
        function PlayFilteredAudioButtonPushed(app, event)
           appliedFilter = app.NoneLabel.Text;
           if strcmp(appliedFilter, 'None')
               msgbox('No filter selected. Cannot Play.')
           else
           cutOffFreq = app.CutoffFrequencyEditField.Value;
                     
            
           audioObject = evalin('base', 'audioObject');
           
           recordedAudio = getaudiodata(audioObject);
           
           lowerLimit = app.CutoffFrequencyEditField.Value;
           upperLimit = app.UpperLimitEditField.Value;
           
           if strcmp(appliedFilter, 'Low-Pass') % Low pass
               filteredAudio = lowpass(recordedAudio, cutOffFreq / (audioObject.SampleRate));
           end 
           
           if strcmp(appliedFilter, 'High-Pass') % High pass
               filteredAudio = highpass(recordedAudio, cutOffFreq / (audioObject.SampleRate));
           end
           
           if strcmp(appliedFilter, 'Band-Pass') % Band pass
               filteredAudio = bandpass(recordedAudio, [(lowerLimit) / (audioObject.SampleRate), (upperLimit) / (audioObject.SampleRate)], audioObject.SampleRate);
           end
           
           if strcmp(appliedFilter, 'Band-Stop') % Bandstop
               filteredAudio = bandstop(recordedAudio, [(lowerLimit) / (audioObject.SampleRate), (upperLimit) / (audioObject.SampleRate)], audioObject.SampleRate);
           end
           
           plot(app.UIAxes, filteredAudio);
           sound(filteredAudio, audioObject.SampleRate);
           end        
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CutoffFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.CutoffFrequencyEditField.Limits = [1 Inf];
            app.CutoffFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @LowPassFilterButtonPushed, true);
            app.CutoffFrequencyEditField.Value = 1;
            app.RecordAudioButton = uibutton(app.UIFigure, 'push');
            app.RecordAudioButton.ButtonPushedFcn = createCallbackFcn(app, @RecordAudioButtonPushed, true);
            app.RecordAudioButton.Text = 'Record Audio';
            app.PlayFilteredAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayFilteredAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredAudioButtonPushed, true);
            app.PlayFilteredAudioButton.Text = 'Play Filtered Audio';
            app.LowPassFilterButton = uibutton(app.UIFigure, 'push');
            app.LowPassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @LowPassFilterButtonPushed, true);
            app.LowPassFilterButton.Text = 'Low-Pass Filter';
            app.HighPassFilterButton = uibutton(app.UIFigure, 'push');
            app.HighPassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @HighPassFilterButtonPushed, true);
            app.HighPassFilterButton.Text = 'High-Pass Filter';
            app.BandPassFilterButton = uibutton(app.UIFigure, 'push');
            app.BandPassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @BandPassFilterButtonPushed, true);
            app.BandPassFilterButton.Text = 'Band-Pass Filter';
            app.BandStopFilterButton = uibutton(app.UIFigure, 'push');
            app.BandStopFilterButton.ButtonPushedFcn = createCallbackFcn(app, @BandStopFilterButtonPushed, true);
            app.BandStopFilterButton.Text = 'Band Stop Filter';
            app.AudioFilterApplicationLabel = uilabel(app.UIFigure);
            app.AudioFilterApplicationLabel.Text = 'Audio Filter Application';
            app.CurrentFilterLabel = uilabel(app.UIFigure);
            app.CurrentFilterLabel.Text = 'Current Filter:';
            app.NoneLabel = uilabel(app.UIFigure);
            app.NoneLabel.Text = 'None';
            app.UpperLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperLimitEditField.Limits = [0 Inf];
            app.UpperLimitEditField.Visible = 'off';
            app.CutoffFrequencyLabel = uilabel(app.UIFigure);
            app.CutoffFrequencyLabel.Text = 'Cutoff Frequency';
            app.UpperLimitLabel = uilabel(app.UIFigure);
            app.UpperLimitLabel.Visible = 'off';
            app.UpperLimitLabel.Text = 'Upper Limit';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Filtered Audio')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Original Audio')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end